classdef lightGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ModeText          matlab.ui.control.Label
        ModeLabel         matlab.ui.control.Label
        StartButton       matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        ToggleModeButton  matlab.ui.control.Button
        QuitButton        matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        config          % Speichern einiger Konfigurationsdaten
        brickObj        
        myTimer
    end
    
    methods (Access = private)
        function readLightTimerCallback(app, timerObj, event)
            value = app.brickObj.sensor4.value;
            if(length(timerObj.UserData.measurementData) > 1)
                timerObj.UserData.measurementData = timerObj.UserData.measurementData(2:end)
                timerObj.UserData.measurementData(end + 1) = value;
            else
                timerObj.UserData.measurementData(1,1) = value;
            end

            plot(app.UIAxes, timerObj.UserData.measurementData);
        end
        
        function readLightTimerStartCallback(app, timerObj, event)
            disp('Timer wurde gestartet');
        end
        
        function readLightTimerStopCallback(app, timerObj, event)
            disp('Timer wurde angehalten');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Speichern einiger Konfigurationsdaten in den app-Properties
            app.config.numberOfValues       = 30;
            app.config.sensorMode           = 'ambient';
            app.config.timerPeriod          = 0.1;
            app.config.timerExecutionMode   = 'fixedDelay';
            app.config.brickConnectionType  = 'usb';
            
            % Bluetooth initialisieren und Verbindung oeffnen
            try
                app.brickObj = lightConnectEV3(app.config.brickConnectionType, ...
                    app.config.sensorMode);
            catch errinfo
                disp('Verbindung konnte nicht hergestellt werden');
                disp(errinfo);
            end
            
            % Daten fuer UserData Eigenschaft des Timer-Objektes vorbereiten
            myUserData.measurementData = zeros(app.config.numberOfValues,1);
            myUserData.brickObj = app.brickObj;
            
            % Timer-Objekt erzeugen, konfigurieren und in den app-Properties speichern
            app.myTimer = timer(...
                'Period',        app.config.timerPeriod, ...
                'ExecutionMode', app.config.timerExecutionMode, ...
                'UserData',      myUserData, ...
                'StartFcn',     @app.readLightTimerStartCallback, ...
                'StopFcn',      @app.readLightTimerStopCallback, ...
                'TimerFcn',     @app.readLightTimerCallback);
            
            % Den aktuellen Mode in der GUI anzeigen
            app.ModeText.Text = app.config.sensorMode;
            
            % Initiales Plotten der Daten (muss man nicht unbedingt machen)
            plot(app.UIAxes, myUserData.measurementData);
            app.UIAxes.YLim = [0 100];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %stop and delete timer
            delete(app.myTimer);

            % stop brick connection
            app.brickObj.disconnect();

            % delete plot figure
            %delete(app.UIAxes);
            
            % Hint: delete(app) closes the figure
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            R = get(app.myTimer, 'Running');
            if isequal(R ,'off')
                fprintf('Timer is not running\n');
                app.myTimer.start()
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            R = get(app.myTimer, 'Running');
            if isequal(R ,'on')
                fprintf('Timer is running\n');
                app.myTimer.stop()
            end
        end

        % Button pushed function: ToggleModeButton
        function ToggleModeButtonPushed(app, event)
            R = get(app.myTimer, 'Running');
            if isequal(R ,'on')
                app.myTimer.stop()
            end

            if isequal(app.config.sensorMode ,'ambient')
                app.config.sensorMode = 'reflect';
                app.brickObj.sensor4.mode = DeviceMode.Color.Reflect;
            elseif isequal(app.config.sensorMode ,'reflect')
                app.config.sensorMode = 'ambient';
                app.brickObj.sensor4.mode = DeviceMode.Color.Ambient;
            end


            app.ModeText.Text = app.config.sensorMode;
            
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ToggleModeButton = uibutton(app.UIFigure, 'push');
            app.ToggleModeButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleModeButtonPushed, true);
            app.ToggleModeButton.Text = 'Toggle Mode';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'yo mom gay'};
            app.StartButton.Text = 'Start';
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode:';
            app.ModeText = uilabel(app.UIFigure);
            app.ModeText.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lightGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end