classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InfoPanel_2                  matlab.ui.container.Panel
        ScanprogressGauge            matlab.ui.control.LinearGauge
        ScanprogressGaugeLabel       matlab.ui.control.Label
        BusyLamp                     matlab.ui.control.Lamp
        BusyLampLabel                matlab.ui.control.Label
        ConnectedLamp                matlab.ui.control.Lamp
        ConnectedLampLabel           matlab.ui.control.Label
        ControlsPanel                matlab.ui.container.Panel
        notrecommendedLabel          matlab.ui.control.Label
        DisconnectPicklebrickButton  matlab.ui.control.Button
        SolveButton                  matlab.ui.control.Button
        ScanButton                   matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        SpeedKnob                    matlab.ui.control.DiscreteKnob
        SpeedKnobLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        handle;
        scan_done = 0;


        values;
        MAX_Y = 14;
        MAX_X = 12;
        xTarget;
        yTarget;
        xStart;
        yStart;

        speed = 1;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnectedLamp.Color = [1,0,0];

            app.UIAxes.XLim = [0 app.MAX_X+1];
            app.UIAxes.YLim = [0 app.MAX_Y+1];
            grid( app.UIAxes, 'on' )
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
                
        app.handle = EV3();
        app.handle.connect('usb','beep','on')

        app.ConnectedLamp.Color = [0,1,0];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.handle.disconnect()

            delete(app)
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            if app.handle.isConnected
                app.scan_done = 0;
                app.BusyLamp.Color = [1,0,0];
    
                hold( app.UIAxes, 'on' );
                cla(app.UIAxes);

                [vals, xtarget, ytarget, xstart, ystart] = maze_scan(app.handle, app.UIAxes, app.speed, app.ScanprogressGauge, app.MAX_X, app.MAX_Y);
    
                app.values = vals;
                app.xTarget = xtarget;
                app.yTarget = ytarget;
                app.xStart = xstart;
                app.yStart = ystart;
    
                app.scan_done = 1;
                app.BusyLamp.Color = [0,1,0];
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            if app.scan_done == 1
                solve_maze(app.UIAxes, app.values, app.MAX_X, app.MAX_Y, app.xTarget, app.yTarget, app.xStart, app.yStart);
            end
        end

        % Button pushed function: DisconnectPicklebrickButton
        function DisconnectPicklebrickButtonPushed(app, event)
            %app.handle.disconnect();
            app.ConnectedLamp.Color = [1,0,0];
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            value = app.SpeedKnob.Value;

            if value == "Slow"
                app.speed = 1.5;
            end

            if value == "Normal"
                app.speed = 2;
            end

            if value == "V-Tec"
                app.speed = 3;
            end

            if value == "🅱️iturbo"
                app.speed = 4;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scan')
            xlabel(app.UIAxes, 'Breite')
            ylabel(app.UIAxes, 'Länge')
            zlabel(app.UIAxes, 'Z')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.SpeedKnobLabel = uilabel(app.ControlsPanel);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.ControlsPanel, 'discrete');
            app.SpeedKnob.Items = {'Slow', 'Normal', 'V-Tec', '🅱️iturbo'};
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.Tooltip = {'gas, gas, gaaaas, i''m gonna step on the gas tooonight'};
            app.SpeedKnob.Value = 'Normal';
            app.ConnectButton = uibutton(app.ControlsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ScanButton = uibutton(app.ControlsPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.SolveButton = uibutton(app.ControlsPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.DisconnectPicklebrickButton = uibutton(app.ControlsPanel, 'push');
            app.DisconnectPicklebrickButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectPicklebrickButtonPushed, true);
            app.DisconnectPicklebrickButton.Text = 'Disconnect Picklebrick';
            app.notrecommendedLabel = uilabel(app.ControlsPanel);
            app.notrecommendedLabel.Text = '(not recommended)';
            app.InfoPanel_2 = uipanel(app.UIFigure);
            app.InfoPanel_2.Title = 'Info';
            app.ConnectedLampLabel = uilabel(app.InfoPanel_2);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.InfoPanel_2);
            app.BusyLampLabel = uilabel(app.InfoPanel_2);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.InfoPanel_2);
            app.ScanprogressGaugeLabel = uilabel(app.InfoPanel_2);
            app.ScanprogressGaugeLabel.Text = 'Scan progress';
            app.ScanprogressGauge = uigauge(app.InfoPanel_2, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end